classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Th6EditField       matlab.ui.control.EditField
        Th6EditFieldLabel  matlab.ui.control.Label
        Th5EditField       matlab.ui.control.EditField
        Th5EditFieldLabel  matlab.ui.control.Label
        Th4EditField       matlab.ui.control.EditField
        Th4EditFieldLabel  matlab.ui.control.Label
        RzEditField        matlab.ui.control.EditField
        RzEditFieldLabel   matlab.ui.control.Label
        RyEditField        matlab.ui.control.EditField
        RyEditFieldLabel   matlab.ui.control.Label
        RxEditField        matlab.ui.control.EditField
        RxEditFieldLabel   matlab.ui.control.Label
        ClearButton        matlab.ui.control.Button
        DisplayButton      matlab.ui.control.Button
        Th3EditField       matlab.ui.control.EditField
        Th3EditFieldLabel  matlab.ui.control.Label
        Th2EditField       matlab.ui.control.EditField
        Th2EditFieldLabel  matlab.ui.control.Label
        Th1EditField       matlab.ui.control.EditField
        Th1EditFieldLabel  matlab.ui.control.Label
        ZEditField         matlab.ui.control.EditField
        ZEditFieldLabel    matlab.ui.control.Label
        YEditField         matlab.ui.control.EditField
        YEditFieldLabel    matlab.ui.control.Label
        XEditField         matlab.ui.control.EditField
        XEditFieldLabel    matlab.ui.control.Label
        InverseButton      matlab.ui.control.Button
        ForwardButton      matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            Th1 = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B4:B4'); 
            Th2 = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B5:B5'); 
            Th3 = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B6:B6'); 
            Th4 =readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B7:B7'); 
            Th5 =readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B8:B8'); 
            Th6 =readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B9:B9'); 
            T1 = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','E4:E9');
            D_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','D13:D18');
         Theta_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B13:B18');
         Alpha_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','C13:C18');
         A_R     =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','E13:E18');

L(1) = Link([Theta_R.Var1(1)        D_R.Var1(1)    A_R.Var1(1)      Alpha_R.Var1(1)]);
L(2) = Link([Theta_R.Var1(2)        D_R.Var1(2)    A_R.Var1(2)      Alpha_R.Var1(2)]);
L(3) = Link([Theta_R.Var1(3)        D_R.Var1(3)    A_R.Var1(3)      Alpha_R.Var1(3)]);
L(4) = Link([Theta_R.Var1(4)        D_R.Var1(4)    A_R.Var1(4)      Alpha_R.Var1(4)]);
L(5) = Link([Theta_R.Var1(5)        D_R.Var1(5)    A_R.Var1(5)      Alpha_R.Var1(5)]);
L(6) = Link([Theta_R.Var1(6)        D_R.Var1(6)    A_R.Var1(6)      Alpha_R.Var1(6)]);
Robot = SerialLink(L);
Robot.name = "Robot"
Robot.plot([Th1.Var1(1) Th2.Var1(1) Th3.Var1(1) Th4.Var1(1) Th5.Var1(1) Th6.Var1(1)]);
app.XEditField.Value = num2str(T1.Var1(1));
app.YEditField.Value = num2str(T1.Var1(2));
app.ZEditField.Value = num2str(T1.Var1(3));
app.RxEditField.Value = num2str(T1.Var1(4));
app.RyEditField.Value = num2str(T1.Var1(5));
app.RzEditField.Value = num2str(T1.Var1(6));

        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
P_x     = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','O4:O4'); 
P_y     = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','O5:O5'); 
P_z     = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','O6:O6'); 
P_RX    = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','O7:O7'); 
P_RY    = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','O8:O8'); 
P_RZ    = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','O9:O9'); 
R1      = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','Q4:Q9');
D_R     =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','D13:D18');
Theta_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B13:B18');
Alpha_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','C13:C18');
A_R     =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','E13:E18');

L(1) = Link([Theta_R.Var1(1)        D_R.Var1(1)    A_R.Var1(1)      Alpha_R.Var1(1)]);
L(2) = Link([Theta_R.Var1(2)        D_R.Var1(2)    A_R.Var1(2)      Alpha_R.Var1(2)]);
L(3) = Link([Theta_R.Var1(3)        D_R.Var1(3)    A_R.Var1(3)      Alpha_R.Var1(3)]);
L(4) = Link([Theta_R.Var1(4)        D_R.Var1(4)    A_R.Var1(4)      Alpha_R.Var1(4)]);
L(5) = Link([Theta_R.Var1(5)        D_R.Var1(5)    A_R.Var1(5)      Alpha_R.Var1(5)]);
L(6) = Link([Theta_R.Var1(6)        D_R.Var1(6)    A_R.Var1(6)      Alpha_R.Var1(6)]);
Robot = SerialLink(L);
Robot.name = "Robot"
app.Th1EditField.Value = string(R1.Var1(1));
app.Th2EditField.Value = string(R1.Var1(2));
app.Th3EditField.Value = string(R1.Var1(3));
app.Th4EditField.Value = string(R1.Var1(4));
app.Th5EditField.Value = string(R1.Var1(5));
app.Th6EditField.Value = string(R1.Var1(6));
j(1) = R1.Var1(1);
j(2) = R1.Var1(2);
j(3) = R1.Var1(3);
j(4) = R1.Var1(4);
j(5) = R1.Var1(5);
j(6) = R1.Var1(6);
Robot.plot(j);
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
                 D_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','D13:D18');
         Theta_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','B13:B18');
         Alpha_R =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','C13:C18');
         A_R     =  readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','E13:E18');

L(1) = Link([Theta_R.Var1(1)        D_R.Var1(1)    A_R.Var1(1)      Alpha_R.Var1(1)]);
L(2) = Link([Theta_R.Var1(2)        D_R.Var1(2)    A_R.Var1(2)      Alpha_R.Var1(2)]);
L(3) = Link([Theta_R.Var1(3)        D_R.Var1(3)    A_R.Var1(3)      Alpha_R.Var1(3)]);
L(4) = Link([Theta_R.Var1(4)        D_R.Var1(4)    A_R.Var1(4)      Alpha_R.Var1(4)]);
L(5) = Link([Theta_R.Var1(5)        D_R.Var1(5)    A_R.Var1(5)      Alpha_R.Var1(5)]);
L(6) = Link([Theta_R.Var1(6)        D_R.Var1(6)    A_R.Var1(6)      Alpha_R.Var1(6)]);

            workspace_x = [];
            workspace_y = [];
            workspace_z = [];
            workspace_k = [];
            workspace_f = [];
            workspace_e = [];

            Robot = SerialLink(L);
            Robot.name = 'Robot';

     theta1_range = linspace(-pi, pi, 20);
     theta2_range = linspace(-pi, pi, 20);
     theta3_range = linspace(-pi, pi, 20);
     theta4_range = linspace(-pi, pi, 20);
     theta5_range = linspace(-pi, pi, 20);
     theta6_range = linspace(-pi, pi, 20);

   for i = 1:length(theta1_range)
       for j = 1:length(theta2_range)
           for k = 1:length(theta3_range)
             for r = 1:length(theta4_range)
                for m = 1:length(theta5_range)
                    for n = 1:length(theta6_range)
                T1 = readtable('kinematic model AR4-MK3 standard DH.xlsm.xlsx','Range','E4:E9');
                T =T1.Var1;

                x = T1.Var1(1);
                y = T1.Var1(2);
                z = T1.Var1(3);
                b = T1.Var1(4);
                a = T1.Var1(5);
                e = T1.Var1(6);
                workspace_x = [workspace_x; x];
                workspace_y = [workspace_y; y];
                workspace_z = [workspace_z; z];
                workspace_x = [workspace_k; x];
                workspace_y = [workspace_f; y];
                workspace_z = [workspace_e; z];
                     end
                 end
              end
           end
       end
   end
    % Visualize workspace in App Designer axes
    
    %scatter3(workspace_x, workspace_y, workspace_z, '.', 'b');
    figure;
    scatter3(workspace_x, workspace_y, workspace_z, workspace_k, workspace_f, workspace_e, '.', 'b');
    grid on;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            s = 0
            x= num2str(s)
            app.Th1EditField.Value = x;
            app.Th2EditField.Value = x;
            app.Th3EditField.Value =x;
            app.Th4EditField.Value = x;
            app.Th5EditField.Value = x;
            app.Th6EditField.Value =x;

            app.XEditField.Value = x;
            app.YEditField.Value = x;
            app.ZEditField.Value = x;
            app.RxEditField.Value = x;
            app.RzEditField.Value = x;
            app.RyEditField.Value = x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.Th1EditFieldLabel = uilabel(app.UIFigure);
            app.Th1EditFieldLabel.Text = 'Th1';
            app.Th1EditField = uieditfield(app.UIFigure, 'text');
            app.Th2EditFieldLabel = uilabel(app.UIFigure);
            app.Th2EditFieldLabel.Text = 'Th2';
            app.Th2EditField = uieditfield(app.UIFigure, 'text');
            app.Th3EditFieldLabel = uilabel(app.UIFigure);
            app.Th3EditFieldLabel.Text = 'Th3';
            app.Th3EditField = uieditfield(app.UIFigure, 'text');
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RxEditFieldLabel = uilabel(app.UIFigure);
            app.RxEditFieldLabel.Text = 'Rx';
            app.RxEditField = uieditfield(app.UIFigure, 'text');
            app.RyEditFieldLabel = uilabel(app.UIFigure);
            app.RyEditFieldLabel.Text = 'Ry';
            app.RyEditField = uieditfield(app.UIFigure, 'text');
            app.RzEditFieldLabel = uilabel(app.UIFigure);
            app.RzEditFieldLabel.Text = 'Rz';
            app.RzEditField = uieditfield(app.UIFigure, 'text');
            app.Th4EditFieldLabel = uilabel(app.UIFigure);
            app.Th4EditFieldLabel.Text = 'Th4';
            app.Th4EditField = uieditfield(app.UIFigure, 'text');
            app.Th5EditFieldLabel = uilabel(app.UIFigure);
            app.Th5EditFieldLabel.Text = 'Th5';
            app.Th5EditField = uieditfield(app.UIFigure, 'text');
            app.Th6EditFieldLabel = uilabel(app.UIFigure);
            app.Th6EditFieldLabel.Text = 'Th6';
            app.Th6EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end